classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DeforestationAnalyzerUIFigure  matlab.ui.Figure
        UploadButton                   matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        mmEditFieldLabel               matlab.ui.control.Label
        mmEditField                    matlab.ui.control.NumericEditField
        kmLabel                        matlab.ui.control.Label
        kmEditField                    matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        ForestAreaNameEditFieldLabel   matlab.ui.control.Label
        ForestAreaName                 matlab.ui.control.EditField
        YearMonthEditFieldLabel        matlab.ui.control.Label
        Year                           matlab.ui.control.EditField
        NextButton                     matlab.ui.control.Button
        ScaleLabel                     matlab.ui.control.Label
        ProcessSatelliteImageStep01Label  matlab.ui.control.Label
    end


    properties (Access = private)
        Second1App; %First App
        forest = [];
        image = [];
        year = [];
        scale = [];
    end


    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
         [file, path] = uigetfile('*'); 
         app.image = imread(file);
         imshow(app.image, 'parent', app.UIAxes);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            %set scale value
            mm = app.mmEditField.Value;
            km = app.kmEditField.Value;
            scal = (km*1000000)/mm;
            %assign variables
            app.forest = app.ForestAreaName.Value;
            app.year = app.Year.Value;
            app.scale = scal;
            app.Second1App = app2(app,app.image, app.forest, app.year, app.scale);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DeforestationAnalyzerUIFigure = uifigure;
            app.DeforestationAnalyzerUIFigure.Name = 'Deforestation Analyzer';
            app.UploadButton = uibutton(app.DeforestationAnalyzerUIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload Satellite Image';
            app.UIAxes = uiaxes(app.DeforestationAnalyzerUIFigure);
            title(app.UIAxes, 'Uploded Satelite Image')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {'[ ]'};
            app.mmEditFieldLabel = uilabel(app.DeforestationAnalyzerUIFigure);
            app.mmEditFieldLabel.Text = 'mm';
            app.mmEditField = uieditfield(app.DeforestationAnalyzerUIFigure, 'numeric');
            app.kmLabel = uilabel(app.DeforestationAnalyzerUIFigure);
            app.kmLabel.Text = 'km';
            app.kmEditField = uieditfield(app.DeforestationAnalyzerUIFigure, 'numeric');
            app.Label = uilabel(app.DeforestationAnalyzerUIFigure);
            app.Label.Text = ':';
            app.ForestAreaNameEditFieldLabel = uilabel(app.DeforestationAnalyzerUIFigure);
            app.ForestAreaNameEditFieldLabel.Text = 'Forest Area Name';
            app.ForestAreaName = uieditfield(app.DeforestationAnalyzerUIFigure, 'text');
            app.YearMonthEditFieldLabel = uilabel(app.DeforestationAnalyzerUIFigure);
            app.YearMonthEditFieldLabel.Text = 'Year/Month';
            app.Year = uieditfield(app.DeforestationAnalyzerUIFigure, 'text');
            app.NextButton = uibutton(app.DeforestationAnalyzerUIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ScaleLabel = uilabel(app.DeforestationAnalyzerUIFigure);
            app.ScaleLabel.Text = 'Scale';
            app.ProcessSatelliteImageStep01Label = uilabel(app.DeforestationAnalyzerUIFigure);
            app.ProcessSatelliteImageStep01Label.Text = 'Process Satellite Image :  Step - 01';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DeforestationAnalyzerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DeforestationAnalyzerUIFigure)
        end
    end
end