classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DeforestationAnalyzerUIFigure  matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        Label1                         matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        NextButton                     matlab.ui.control.Button
        Label2                         matlab.ui.control.Label
        Label3                         matlab.ui.control.Label
        ForestAreaLabel                matlab.ui.control.Label
        YearMonthLabel                 matlab.ui.control.Label
        Scale1Label                    matlab.ui.control.Label
        ProcessSatelliteImageStep02Label  matlab.ui.control.Label
    end


    properties (Access = private)
        First2App;
        Third2App;
        adjimg;
        forest1=[];
        year1=[];
        scale1=[];
        orgimg;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app1, image, forest, year, scale)
            app.First2App = app1;
            imshow(image, 'Parent', app.UIAxes);
            app.Label1.Text = forest;
            app.Label2.Text = year;
            app.Label3.Text = num2str(scale);
            grayimg = rgb2gray(image);
            app.adjimg = imadjust(grayimg);
            imshow(app.adjimg, 'Parent', app.UIAxes2);
            app.orgimg = image;
            
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.forest1 = app.Label1.Text;
            app.year1 = app.Label2.Text;
            app.scale1 = app.Label3.Text;
            app.Third2App = app3(app, app.adjimg, app.forest1, app.year1, app.scale1, app.orgimg);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DeforestationAnalyzerUIFigure = uifigure;
            app.DeforestationAnalyzerUIFigure.Name = 'Deforestation Analyzer';
            app.UIAxes = uiaxes(app.DeforestationAnalyzerUIFigure);
            title(app.UIAxes, 'Orginal Satalite Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {'[ ]'};
            app.Label1 = uilabel(app.DeforestationAnalyzerUIFigure);
            app.Label1.Text = 'Label1';
            app.UIAxes2 = uiaxes(app.DeforestationAnalyzerUIFigure);
            title(app.UIAxes2, 'Adjusted Grayscale Image')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.NextButton = uibutton(app.DeforestationAnalyzerUIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.Label2 = uilabel(app.DeforestationAnalyzerUIFigure);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.DeforestationAnalyzerUIFigure);
            app.Label3.Text = 'Label3';
            app.ForestAreaLabel = uilabel(app.DeforestationAnalyzerUIFigure);
            app.ForestAreaLabel.Text = 'Forest Area:';
            app.YearMonthLabel = uilabel(app.DeforestationAnalyzerUIFigure);
            app.YearMonthLabel.Text = 'Year/ Month';
            app.Scale1Label = uilabel(app.DeforestationAnalyzerUIFigure);
            app.Scale1Label.Text = 'Scale: 1: ';
            app.ProcessSatelliteImageStep02Label = uilabel(app.DeforestationAnalyzerUIFigure);
            app.ProcessSatelliteImageStep02Label.Text = 'Process Satellite Image :  Step - 02';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DeforestationAnalyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DeforestationAnalyzerUIFigure)
        end
    end
end