classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DeforestationAnalyzerUIFigure  matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        Label1                         matlab.ui.control.Label
        Label2                         matlab.ui.control.Label
        Label3                         matlab.ui.control.Label
        ForestAreaLabel                matlab.ui.control.Label
        YearMonthLabel                 matlab.ui.control.Label
        Scale1Label                    matlab.ui.control.Label
        NextButton                     matlab.ui.control.Button
        ProcessSatelliteImageStep03Label  matlab.ui.control.Label
        PixelSpinnerLabel              matlab.ui.control.Label
        PixelSpinner                   matlab.ui.control.Spinner
    end


    properties (Access = private)
        Second3App;
        Final3App;
        finalimg;
        width = 3;
        adj3img;
        forest2=[];
        year2=[];
        scale2=[];
        orgimg;
    end

    methods (Access = private)
    
        function updatefinalimage(app, adjimg, width)
            BW = imbinarize(adjimg);
            app.width = app.PixelSpinner.Value;
            se = strel('square', width);
            BW = imopen(BW, se);
            maskedImage = adjimg;
            maskedImage(~BW) = 0;
            app.finalimg = maskedImage;
            imshow(app.finalimg, 'Parent', app.UIAxes_2);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app2, adjimg, forest1, year1, scale1, orgimg)
            app.Second3App = app2;
            imshow(adjimg, 'Parent', app.UIAxes);
            app.Label1.Text = forest1;
            app.Label2.Text = year1;
            app.Label3.Text = scale1;
            updatefinalimage(app, adjimg, app.width);
            app.adj3img = adjimg;
            app.orgimg = orgimg;
            %Thresholding and Mask Function
            
            
        end

        % Callback function
        function PixelSmoothnessSliderValueChanged(app, event)

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.forest2 = app.Label1.Text;
            app.year2 = app.Label2.Text;
            app.scale2 = app.Label3.Text;
            app.Final3App = app4(app, app.finalimg, app.forest2, app.year2, app.scale2, app.orgimg);
            delete(app);
        end

        % Callback function
        function PixelSmoothnessSliderValueChanging(app, event)
            
        end

        % Value changed function: PixelSpinner
        function PixelSpinnerValueChanged(app, event)

            updatefinalimage(app, app.adj3img, app.PixelSpinner.Value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DeforestationAnalyzerUIFigure = uifigure;
            app.DeforestationAnalyzerUIFigure.Name = 'Deforestation Analyzer';
            app.UIAxes = uiaxes(app.DeforestationAnalyzerUIFigure);
            title(app.UIAxes, 'Adjusted Grayscale Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.DeforestationAnalyzerUIFigure);
            title(app.UIAxes_2, 'Final Segmented Image')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.Label1 = uilabel(app.DeforestationAnalyzerUIFigure);
            app.Label1.Text = 'Label1';
            app.Label2 = uilabel(app.DeforestationAnalyzerUIFigure);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.DeforestationAnalyzerUIFigure);
            app.Label3.Text = 'Label3';
            app.ForestAreaLabel = uilabel(app.DeforestationAnalyzerUIFigure);
            app.ForestAreaLabel.Text = 'Forest Area:';
            app.YearMonthLabel = uilabel(app.DeforestationAnalyzerUIFigure);
            app.YearMonthLabel.Text = 'Year/ Month';
            app.Scale1Label = uilabel(app.DeforestationAnalyzerUIFigure);
            app.Scale1Label.Text = 'Scale: 1: ';
            app.NextButton = uibutton(app.DeforestationAnalyzerUIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ProcessSatelliteImageStep03Label = uilabel(app.DeforestationAnalyzerUIFigure);
            app.ProcessSatelliteImageStep03Label.Text = 'Process Satellite Image :  Step - 03';
            app.PixelSpinnerLabel = uilabel(app.DeforestationAnalyzerUIFigure);
            app.PixelSpinnerLabel.Text = 'Pixel';
            app.PixelSpinner = uispinner(app.DeforestationAnalyzerUIFigure);
            app.PixelSpinner.Limits = [0 Inf];
            app.PixelSpinner.RoundFractionalValues = 'on';
            app.PixelSpinner.ValueChangedFcn = createCallbackFcn(app, @PixelSpinnerValueChanged, true);
            app.PixelSpinner.Value = 3;
        end
    end

    methods (Access = public)

        % Construct app
        function app = app3(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DeforestationAnalyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DeforestationAnalyzerUIFigure)
        end
    end
end