classdef app4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DeforestationAnalyzerUIFigure  matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        Label1                         matlab.ui.control.Label
        Label2                         matlab.ui.control.Label
        Label3                         matlab.ui.control.Label
        ForestAreaLabel                matlab.ui.control.Label
        YearMonthLabel                 matlab.ui.control.Label
        Scale1Label                    matlab.ui.control.Label
        DeforestedAreakm2Label         matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        ProcessSatelliteImageFinalStepLabel  matlab.ui.control.Label
        HomeButton                     matlab.ui.control.Button
        ExitButton                     matlab.ui.control.Button
    end


    properties (Access = private)
        Third4App;
        First4App;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app3, finalimg, forest2, year2, scale2, orgimg)
            app.Third4App = app3;
            imshow(orgimg, 'Parent', app.UIAxes);
            imshow(finalimg, 'Parent', app.UIAxes_2);
            app.Label1.Text = forest2;
            app.Label2.Text = year2;
            app.Label3.Text = scale2;
            props = regionprops('table',finalimg,'Area');
            areaPixels = sum(props.Area);
            scl = str2double(scale2);
            p = (25.4*1000000)/(scl*99);
            px2km = (p)^2;
            areaKmSq = round(areaPixels*px2km);
            %areaKmSq = bwarea(finalimg);
            app.Label.Text = num2str(areaKmSq);


        end

        % Close request function: DeforestationAnalyzerUIFigure
        function DeforestationAnalyzerUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app0;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DeforestationAnalyzerUIFigure = uifigure;
            app.DeforestationAnalyzerUIFigure.Name = 'Deforestation Analyzer';
            app.DeforestationAnalyzerUIFigure.CloseRequestFcn = createCallbackFcn(app, @DeforestationAnalyzerUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.DeforestationAnalyzerUIFigure);
            title(app.UIAxes, 'Orginal Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.DeforestationAnalyzerUIFigure);
            title(app.UIAxes_2, 'Final Segmented Image')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.Label1 = uilabel(app.DeforestationAnalyzerUIFigure);
            app.Label1.Text = 'Label1';
            app.Label2 = uilabel(app.DeforestationAnalyzerUIFigure);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.DeforestationAnalyzerUIFigure);
            app.Label3.Text = 'Label3';
            app.ForestAreaLabel = uilabel(app.DeforestationAnalyzerUIFigure);
            app.ForestAreaLabel.Text = 'Forest Area:';
            app.YearMonthLabel = uilabel(app.DeforestationAnalyzerUIFigure);
            app.YearMonthLabel.Text = 'Year/ Month';
            app.Scale1Label = uilabel(app.DeforestationAnalyzerUIFigure);
            app.Scale1Label.Text = 'Scale: 1: ';
            app.DeforestedAreakm2Label = uilabel(app.DeforestationAnalyzerUIFigure);
            app.DeforestedAreakm2Label.Text = 'Deforested Area (km^2): ';
            app.Label = uilabel(app.DeforestationAnalyzerUIFigure);
            app.Label.Text = '';
            app.ProcessSatelliteImageFinalStepLabel = uilabel(app.DeforestationAnalyzerUIFigure);
            app.ProcessSatelliteImageFinalStepLabel.Text = 'Process Satellite Image :  Final Step';
            app.HomeButton = uibutton(app.DeforestationAnalyzerUIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.ExitButton = uibutton(app.DeforestationAnalyzerUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app4(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DeforestationAnalyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DeforestationAnalyzerUIFigure)
        end
    end
end