classdef appA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UploadButton                  matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        Label                         matlab.ui.control.Label
        NextButton                    matlab.ui.control.Button
        ScaleLabel                    matlab.ui.control.Label
        ProcessSatelliteImageStep01Label  matlab.ui.control.Label
        mmEditFieldLabel              matlab.ui.control.Label
        mmEditField                   matlab.ui.control.NumericEditField
        kmEditFieldLabel              matlab.ui.control.Label
        kmEditField                   matlab.ui.control.NumericEditField
        ForestAreaNameEditFieldLabel  matlab.ui.control.Label
        ForestAreaName                matlab.ui.control.EditField
        YearMonthEditFieldLabel       matlab.ui.control.Label
        Year                          matlab.ui.control.EditField
        UploadButton_2                matlab.ui.control.Button
        UIAxes_2                      matlab.ui.control.UIAxes
        YearMonthEditFieldLabel_2     matlab.ui.control.Label
        Year_2                        matlab.ui.control.EditField
    end


    properties (Access = private)
        Second1App; %First App
        forest = [];
        imageA = [];
        imageB = [];
        yearA = [];
        yearB = [];
        scale = [];
    end


    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [file,path] = uigetfile('*'); 
         app.imageA = imread(file);
         imshow(app.imageA, 'parent', app.UIAxes);
        end

        % Button pushed function: UploadButton_2
        function UploadButton_2Pushed(app, event)
            [file,path] = uigetfile('*'); 
         app.imageB = imread(file);
         imshow(app.imageB, 'parent', app.UIAxes_2);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            mm = app.mmEditField.Value;
            km = app.kmEditField.Value;
            scal = (km*1000000)/mm;
            %assign variables
            app.forest = app.ForestAreaName.Value;
            app.yearA = app.Year.Value;
            app.yearB = app.Year_2.Value;
            app.scale = scal;
            app.Second1App = appB(app,app.imageA, app.imageB, app.forest, app.yearA, app.yearB, app.scale);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload First Image';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Uploded First Image')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {'[ ]'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = ':';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ScaleLabel = uilabel(app.UIFigure);
            app.ScaleLabel.Text = 'Scale';
            app.ProcessSatelliteImageStep01Label = uilabel(app.UIFigure);
            app.ProcessSatelliteImageStep01Label.Text = 'Process Satellite Image :  Step - 01';
            app.mmEditFieldLabel = uilabel(app.UIFigure);
            app.mmEditFieldLabel.Text = 'mm';
            app.mmEditField = uieditfield(app.UIFigure, 'numeric');
            app.kmEditFieldLabel = uilabel(app.UIFigure);
            app.kmEditFieldLabel.Text = 'km';
            app.kmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ForestAreaNameEditFieldLabel = uilabel(app.UIFigure);
            app.ForestAreaNameEditFieldLabel.Text = 'Forest Area Name';
            app.ForestAreaName = uieditfield(app.UIFigure, 'text');
            app.YearMonthEditFieldLabel = uilabel(app.UIFigure);
            app.YearMonthEditFieldLabel.Text = 'Year/Month';
            app.Year = uieditfield(app.UIFigure, 'text');
            app.UploadButton_2 = uibutton(app.UIFigure, 'push');
            app.UploadButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadButton_2Pushed, true);
            app.UploadButton_2.Text = 'Upload Second Image';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Uploded Second Image')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabel = {'[ ]'};
            app.YearMonthEditFieldLabel_2 = uilabel(app.UIFigure);
            app.YearMonthEditFieldLabel_2.Text = 'Year/Month';
            app.Year_2 = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = appA

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end