classdef appC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        UIAxes_2             matlab.ui.control.UIAxes
        Label1               matlab.ui.control.Label
        Label2               matlab.ui.control.Label
        Label3               matlab.ui.control.Label
        ForestAreaLabel      matlab.ui.control.Label
        YearMonthLabel       matlab.ui.control.Label
        Scale1Label          matlab.ui.control.Label
        NextButton           matlab.ui.control.Button
        ProcessSatelliteImageStep03Label  matlab.ui.control.Label
        PixelSpinnerLabel    matlab.ui.control.Label
        PixelSpinner         matlab.ui.control.Spinner
        PixelSpinner_2Label  matlab.ui.control.Label
        PixelSpinner_2       matlab.ui.control.Spinner
    end


    properties (Access = private)
        Second3App;
        Final3App;
        finalimgA;
        finalimgB;
        widthA = 3;
        widthB = 3;
        adj3imgA;
        adj3imgB;
        forest2=[];
        year2=[];
        scale2=[];
        orgimg; % Description
    end

    methods (Access = private)
    
        function updatefinalimageA(app, adjimgA, widthA)
            BW = imbinarize(adjimgA);
            app.widthA = app.PixelSpinner.Value;
            se = strel('square', widthA);
            BW = imopen(BW, se);
            maskedImage = adjimgA;
            maskedImage(~BW) = 0;
            app.finalimgA = maskedImage;
            imshow(app.finalimgA, 'Parent', app.UIAxes);
            
        end
        
        function updatefinalimageB(app, adjimgB, widthB)
            BW = imbinarize(adjimgB);
            app.widthB = app.PixelSpinner.Value;
            se = strel('square', widthB);
            BW = imopen(BW, se);
            maskedImage = adjimgB;
            maskedImage(~BW) = 0;
            app.finalimgB = maskedImage;
            imshow(app.finalimgB, 'Parent', app.UIAxes_2);
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, appB, adjimgA, adjimgB, forest1, year1, scale1, orgimg)
            app.Second3App = appB;
            app.Label1.Text = forest1;
            app.Label2.Text = year1;
            app.Label3.Text = scale1;
            updatefinalimageA(app, adjimgA, app.widthA);
            updatefinalimageB(app, adjimgB, app.widthB);
            app.adj3imgA = adjimgA;
            app.adj3imgB = adjimgB;
            app.orgimg = orgimg;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.forest2 = app.Label1.Text;
            app.year2 = app.Label2.Text;
            app.scale2 = app.Label3.Text;
            app.Final3App = appD(app, app.finalimgA, app.finalimgB, app.forest2, app.year2, app.scale2, app.orgimg);
            delete(app);
        end

        % Value changed function: PixelSpinner
        function PixelSpinnerValueChanged(app, event)
            updatefinalimageA(app, app.adj3imgA, app.PixelSpinner.Value);
            
        end

        % Value changed function: PixelSpinner_2
        function PixelSpinner_2ValueChanged(app, event)
            updatefinalimageB(app, app.adj3imgB, app.PixelSpinner_2.Value);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Final Segmented Image 01')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Final Segmented Image 01')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.Label1 = uilabel(app.UIFigure);
            app.Label1.Text = 'Label1';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Label3';
            app.ForestAreaLabel = uilabel(app.UIFigure);
            app.ForestAreaLabel.Text = 'Forest Area:';
            app.YearMonthLabel = uilabel(app.UIFigure);
            app.YearMonthLabel.Text = 'Year/ Month';
            app.Scale1Label = uilabel(app.UIFigure);
            app.Scale1Label.Text = 'Scale: 1: ';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ProcessSatelliteImageStep03Label = uilabel(app.UIFigure);
            app.ProcessSatelliteImageStep03Label.Text = 'Process Satellite Image :  Step - 03';
            app.PixelSpinnerLabel = uilabel(app.UIFigure);
            app.PixelSpinnerLabel.Text = 'Pixel';
            app.PixelSpinner = uispinner(app.UIFigure);
            app.PixelSpinner.Limits = [0 Inf];
            app.PixelSpinner.RoundFractionalValues = 'on';
            app.PixelSpinner.ValueChangedFcn = createCallbackFcn(app, @PixelSpinnerValueChanged, true);
            app.PixelSpinner.Value = 3;
            app.PixelSpinner_2Label = uilabel(app.UIFigure);
            app.PixelSpinner_2Label.Text = 'Pixel';
            app.PixelSpinner_2 = uispinner(app.UIFigure);
            app.PixelSpinner_2.Limits = [0 Inf];
            app.PixelSpinner_2.RoundFractionalValues = 'on';
            app.PixelSpinner_2.ValueChangedFcn = createCallbackFcn(app, @PixelSpinner_2ValueChanged, true);
            app.PixelSpinner_2.Value = 3;
        end
    end

    methods (Access = public)

        % Construct app
        function app = appC(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end