classdef appD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        Label1                    matlab.ui.control.Label
        Label2                    matlab.ui.control.Label
        Label3                    matlab.ui.control.Label
        ForestAreaLabel           matlab.ui.control.Label
        YearMonthLabel            matlab.ui.control.Label
        Scale1Label               matlab.ui.control.Label
        DeforestedAreakm2Label    matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        ProcessSatelliteImageFinalStepLabel  matlab.ui.control.Label
        HomeButton                matlab.ui.control.Button
        ExitButton                matlab.ui.control.Button
        DeforestedAreakm2Label_2  matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        UIAxes2                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Third4App;
        First4App; % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, appC, finalimgA, finalimgB, forest2, year2, scale2, orgimg)
            app.Third4App = appC;
            imshow(finalimgA, 'Parent', app.UIAxes);
            imshow(finalimgB, 'Parent', app.UIAxes_2);
            app.Label1.Text = forest2;
            app.Label2.Text = year2;
            app.Label3.Text = scale2;
            props = regionprops('table',finalimgA,'Area');
            areaPixelsA = sum(props.Area);
            scl = str2double(scale2);
            p = (25.4*1000000)/(scl*99);
            px2km = (p)^2;
            areaKmSqA = round(areaPixelsA*px2km);
            %areaKmSq = bwarea(finalimg);
            app.Label.Text = num2str(areaKmSqA);
            
            props = regionprops('table',finalimgB,'Area');
            areaPixelsB = sum(props.Area);
            areaKmSqB = round(areaPixelsB*px2km);
            %areaKmSq = bwarea(finalimg);
            app.Label_2.Text = num2str(areaKmSqB);
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app0;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'First Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Second Image')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.Label1 = uilabel(app.UIFigure);
            app.Label1.Text = 'Label1';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Label3';
            app.ForestAreaLabel = uilabel(app.UIFigure);
            app.ForestAreaLabel.Text = 'Forest Area:';
            app.YearMonthLabel = uilabel(app.UIFigure);
            app.YearMonthLabel.Text = 'Year/ Month';
            app.Scale1Label = uilabel(app.UIFigure);
            app.Scale1Label.Text = 'Scale: 1: ';
            app.DeforestedAreakm2Label = uilabel(app.UIFigure);
            app.DeforestedAreakm2Label.Text = 'Deforested Area (km^2): ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ProcessSatelliteImageFinalStepLabel = uilabel(app.UIFigure);
            app.ProcessSatelliteImageFinalStepLabel.Text = 'Process Satellite Image :  Final Step';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.DeforestedAreakm2Label_2 = uilabel(app.UIFigure);
            app.DeforestedAreakm2Label_2.Text = 'Deforested Area (km^2): ';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = appD(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end